classdef hlavniOkno < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Button                       matlab.ui.control.Button
        FilenameEditField            matlab.ui.control.EditField
        FilenameEditFieldLabel       matlab.ui.control.Label
        ULOZITButton                 matlab.ui.control.Button
        umintenzitaEditField         matlab.ui.control.EditField
        umintenzitaEditFieldLabel    matlab.ui.control.Label
        umfrekvencedoEditField       matlab.ui.control.EditField
        umfrekvencedoEditFieldLabel  matlab.ui.control.Label
        umfrekvenceodEditField       matlab.ui.control.EditField
        umfrekvenceodEditFieldLabel  matlab.ui.control.Label
        frekvencemaskovachotnuEditField  matlab.ui.control.EditField
        frekvencemaskovachotnuEditFieldLabel  matlab.ui.control.Label
        intenzitatestovanhotnuEditField  matlab.ui.control.EditField
        intenzitatestovanhotnuEditFieldLabel  matlab.ui.control.Label
        vektortestovacchfrekvencEditField  matlab.ui.control.EditField
        vektortestovacchfrekvencEditFieldLabel  matlab.ui.control.Label
        SlysenoButton                matlab.ui.control.Button
        STARTButton                  matlab.ui.control.Button
        mask                         matlab.ui.control.NumericEditField
        masknetestucha1ANO0NEEditFieldLabel  matlab.ui.control.Label
        zvysovani                    matlab.ui.control.NumericEditField
        zvysovaniintenzity1schod0spojEditFieldLabel  matlab.ui.control.Label
        NVODButton                   matlab.ui.control.Button
        nastavenhodnotyLabel         matlab.ui.control.Label
        NASTAVENButton               matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = private)

        nastaveni % Description
        navod
        freqVect = [125, 250, 500, 1000, 1500, 2000, 3000, 4000, 6000, 8000];
        startMeasFlag = 0;
        dontSaveTime = 0;
        timeLeft = [];
        timeRight = [];
        measCounter = 1;
        leftMeas = 1;
        path = "C:\Users\pache\Desktop\bakalarka\Bordel\";
        frekvencemaskovacihotonu = [];
        intenzitatestovacihotonu = [];
        sumfrekvenceod = [];
        sumfrekvencedo = [];
        sumintenzita = [];


        steps = [0, 0.1, 0.2, 0.3, 0.4, 0.5, 0.6, 0.7, 0.8, 0.9]; % Values for each segment


    end

    methods (Access = public)
        %hlaní funkce pro přeposílání dat mezi main a nastavením
        function importNastaveni(app,vektorFrekvenci,MaskToneFreqV,MaskToneIntV,MaskLowFreqV,MaskHighFreqV,IntSumV,MaskovaniNetestovanehoUcha,ZvysovaniIntenzityTonu)
            %function importNastaveni(app,MaskovaniNetestovanehoUcha,ZvysovaniIntenzityTonu,frequencyNew,frekvenceMaskTonu,intenzitaMaskTonu,pasmoMinFreq,pasmoMaxFreq,sumIntenzita,vektorFrekvenci)
            app.mask.Value=MaskovaniNetestovanehoUcha;
            app.zvysovani.Value=ZvysovaniIntenzityTonu;

            app.vektortestovacchfrekvencEditField.Value= vektorFrekvenci;
            app.freqVect = str2num(vektorFrekvenci);
            app.frekvencemaskovachotnuEditField.Value = MaskToneFreqV;
            app.frekvencemaskovacihotonu = str2num(MaskToneFreqV);
            app.intenzitatestovanhotnuEditField.Value = MaskToneIntV;
            app.intenzitatestovacihotonu = str2num(MaskToneIntV);
            app.umfrekvenceodEditField.Value = MaskLowFreqV;
            app.sumfrekvenceod = str2num(MaskLowFreqV);
            app.umfrekvencedoEditField.Value = MaskHighFreqV;
            app.sumfrekvencedo = str2num(MaskHighFreqV);
            app.umintenzitaEditField.Value = IntSumV;
            app.sumintenzita = str2num(IntSumV);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % Button pushed function: NASTAVENButton
        function NASTAVENButtonPushed(app, event)
            app.nastaveni=nastaveni(app,app.freqVect,app.frekvencemaskovacihotonu,app.intenzitatestovacihotonu,app.sumfrekvenceod,app.sumfrekvencedo,app.sumintenzita,app.mask.Value,app.zvysovani.Value);
            %app.importdata(app,);

        end

        % Button pushed function: NVODButton
        function NVODButtonPushed(app, event)
            app.navod=navod(app);
        end

        % Value changed function: mask
        function maskValueChanged(app, event)
            value = app.mask.Value;

        end

        % Value changed function: zvysovani
        function zvysovaniValueChanged(app, event)
            value = app.zvysovani.Value;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            % delete(app.nastaveni)
            %delete(app.navod)

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)


            if app.startMeasFlag == 0
                % app.Measurement();
                app.STARTButton.Text = 'RESTART';
                app.STARTButton.BackgroundColor = 'r';
                app.SlysenoButton.BackgroundColor = [0.47,0.67,0.19];
                app.startMeasFlag = 1;
                app.dontSaveTime = 1;
                SlysenoButtonPushed(app);


            elseif app.startMeasFlag == 1
                %app.buttonPushed =1;
                app.STARTButton.Text = 'START';
                app.STARTButton.BackgroundColor = [0.47,0.67,0.19];
                app.SlysenoButton.BackgroundColor = 'r';
                app.startMeasFlag = 0;
                app.measCounter = 1;
                app.leftMeas = 1;
                clear sound;


            end


        end

        % Button pushed function: SlysenoButton
        function SlysenoButtonPushed(app, event)

            Fs = 44100 ;   % nastavení vzorkovacího kmitočtu
            t_max = 60;   %časová délka signálu ale aby v gefu byla tahle hodnota je zapotřebí poděli pi*2(v grafu na x ukáže sice 10 ale)
            t = 0: (2*pi)/Fs : (2*pi*t_max - (2*pi)/Fs); %nastavení časové základny
            f = app.freqVect;%[125, 250, 500, 1000, 1500, 2000, 3000, 4000, 6000, 8000];


            P_sampled = importdata('funkce60.mat');
            P_sampled = P_sampled'; % transpozice vektoru
            P_sampled(end) = [];    % zkrácení vektoru aby měl požadovanou délku

            % app.steps = app.steps.*app.steps;
            stepTime = length(t) / length(app.steps); % Length of each segment

            radFiltru = 6;

            for i = 1:length(app.steps)
                index_range = (i-1)*stepTime + 1 : i*stepTime;
                stepsSignal(index_range) = app.steps(i);
            end

            clear sound;
            if app.dontSaveTime == 0
                if app.leftMeas == 0
                    app.timeRight(app.measCounter) = toc;
                elseif app.leftMeas == 1
                    if app.measCounter > 1
                        app.timeLeft(app.measCounter-1) = toc;
                    end
                end
            else
                app.dontSaveTime =0;
            end


            if app.startMeasFlag == 1
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if ~isempty(app.frekvencemaskovacihotonu)
                    if numel(app.frekvencemaskovacihotonu) == numel(app.intenzitatestovacihotonu)
                        for i =1:numel(app.frekvencemaskovacihotonu)
                            MaskTone = 0.1*app.intenzitatestovacihotonu(i).*sin(app.frekvencemaskovacihotonu(i)*t);
                            sound(MaskTone,Fs);
                        end
                    end
                end
                % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if ~isempty(app.sumfrekvenceod)
                    if numel(app.sumfrekvenceod) == numel(app.sumfrekvencedo) == numel(app.sumintenzita) %nevim proč nefinguje tahle podmínka
                        for i =1:numel(app.sumfrekvenceod)
                            sumWhite = app.sumintenzita(i) * randn(size(t));
                            [b, a] = butter(radFiltru, [app.sumfrekvenceod(i), app.sumfrekvencedo(i)]/(Fs/2), 'bandpass');
                            VyfitrovanySum = filter(b, a, sumWhite)  ;
                            sound(VyfitrovanySum,Fs);
                        end
                    end
                end

                %   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                MaskNetest = 0.1 * randn(size(t));
                [b, a] = butter(4, [10, 20000]/(Fs/2), 'bandpass');
                VyfitrovanyMaskNetest= filter(b, a, MaskNetest)  ;
                %sound(VyfitrovanyMaskNetest,Fs);


                if app.measCounter < numel(f)+1
                    if app.leftMeas == 1
                        frequence = f(app.measCounter);
                        signal = sin(frequence*t);
                        if app.mask.Value == 1
                            sound([zeros(size(t)); VyfitrovanyMaskNetest],Fs);
                        end
                        if app.zvysovani.Value == 0
                            signal = signal.*0.2;%P_sampled;
                        elseif app.zvysovani.Value == 1
                            signal = signal.*stepsSignal;
                        end
                        sound([signal;zeros(size(t))],Fs);
                        tic
                        app.leftMeas = 0;
                    elseif app.leftMeas == 0
                        frequence = f(app.measCounter);
                        signal = sin(frequence*t);
                        if app.mask.Value == 1
                            sound([VyfitrovanyMaskNetest;zeros(size(t))],Fs)
                        end
                        if app.zvysovani.Value == 0
                            signal = signal.*0.2;%P_sampled;
                        elseif app.zvysovani.Value == 1
                            signal = signal.*stepsSignal;
                        end
                        sound([zeros(size(t)); signal],Fs);
                        tic
                        app.measCounter =  app.measCounter + 1;
                        app.leftMeas =1;
                    end


                else
                    %restarting
                    app.startMeasFlag = 0;
                    app.STARTButton.Text = 'START';
                    app.STARTButton.BackgroundColor = [0.47,0.67,0.19];
                    app.SlysenoButton.BackgroundColor = 'r';

                    if app.zvysovani.Value == 0
                        Norm_P_sampled = P_sampled./ max(P_sampled);
                        for k= 1:length(app.timeLeft)
                            timePointLeft(k) = floor((app.timeLeft(k)/t_max)*length(t));
                            ampiltudeLeft(k) = Norm_P_sampled(timePointLeft(k))*100;
                        end
                        for j = 1:length(app.timeRight)
                            timePointRight(j) = floor((app.timeRight(j)/t_max)*length(t));
                            ampiltudeRight(j) = Norm_P_sampled(timePointRight(j))*100;
                        end
                    elseif app.zvysovani.Value == 1
                        app.timeRight = app.timeRight./(t_max/numel(app.steps));
                        app.timeLeft =  app.timeLeft./(t_max/numel(app.steps));
                        ampiltudeRight = floor(app.timeRight).*10;
                        ampiltudeLeft = floor(app.timeLeft).*10;
                    end
                    plot(app.UIAxes,f,ampiltudeRight,'r-x',f,ampiltudeLeft,'b-o');
                    app.measCounter = 1;
                    clear sound;

                end

            end
        end

        % Value changed function: vektortestovacchfrekvencEditField
        function vektortestovacchfrekvencEditFieldValueChanged(app, event)
            value = app.vektortestovacchfrekvencEditField.Value;

        end

        % Value changed function: frekvencemaskovachotnuEditField
        function frekvencemaskovachotnuEditFieldValueChanged(app, event)
            value = app.frekvencemaskovachotnuEditField.Value;

        end

        % Value changed function: intenzitatestovanhotnuEditField
        function intenzitatestovanhotnuEditFieldValueChanged(app, event)
            value = app.intenzitatestovanhotnuEditField.Value;

        end

        % Value changed function: umfrekvenceodEditField
        function umfrekvenceodEditFieldValueChanged(app, event)
            value = app.umfrekvenceodEditField.Value;

        end

        % Value changed function: umfrekvencedoEditField
        function umfrekvencedoEditFieldValueChanged(app, event)
            value = app.umfrekvencedoEditField.Value;

        end

        % Value changed function: umintenzitaEditField
        function umintenzitaEditFieldValueChanged(app, event)
            value = app.umintenzitaEditField.Value;

        end

        % Button pushed function: ULOZITButton
        function ULOZITButtonPushed(app, event)
            %%%%%%%%%%%%%%% zatím jen ukládáme print screen
            screen = getframe(app.UIFigure); 
            str = append(app.path,app.FilenameEditField.Value,".png");
            fileName = convertStringsToChars(str);
            % Uložení obrázku do souboru
            imwrite(screen.cdata, fileName);

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            selectedFolder = uigetdir('C:\Users\pache\Desktop\bakalarka\Bordel\');
            app.path = string(selectedFolder);
            app.path =append(app.path,"\");
            app.umintenzitaEditField.Value = char(app.path);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'AUDIOGRAM')
            xlabel(app.UIAxes, 'Frekvence Hz')
            ylabel(app.UIAxes, 'SPL dB')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 8000];
            app.UIAxes.YLim = [0 90];
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XAxisLocation = 'top';
            app.UIAxes.Tag = 'tagGrafu';
            app.NASTAVENButton = uibutton(app.UIFigure, 'push');
            app.NASTAVENButton.ButtonPushedFcn = createCallbackFcn(app, @NASTAVENButtonPushed, true);
            app.NASTAVENButton.Text = 'NASTAVENÍ';
            app.nastavenhodnotyLabel = uilabel(app.UIFigure);
            app.nastavenhodnotyLabel.Text = 'nastavené hodnoty';
            app.NVODButton = uibutton(app.UIFigure, 'push');
            app.NVODButton.ButtonPushedFcn = createCallbackFcn(app, @NVODButtonPushed, true);
            app.NVODButton.Text = 'NÁVOD';
            app.zvysovaniintenzity1schod0spojEditFieldLabel = uilabel(app.UIFigure);
            app.zvysovaniintenzity1schod0spojEditFieldLabel.Text = 'zvysovani intenzity(1=schod, 0 = spoj)';
            app.zvysovani = uieditfield(app.UIFigure, 'numeric');
            app.zvysovani.ValueChangedFcn = createCallbackFcn(app, @zvysovaniValueChanged, true);
            app.masknetestucha1ANO0NEEditFieldLabel = uilabel(app.UIFigure);
            app.masknetestucha1ANO0NEEditFieldLabel.Text = 'mask netest ucha (1= ANO, 0 = NE)';
            app.mask = uieditfield(app.UIFigure, 'numeric');
            app.mask.ValueChangedFcn = createCallbackFcn(app, @maskValueChanged, true);
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.SlysenoButton = uibutton(app.UIFigure, 'push');
            app.SlysenoButton.ButtonPushedFcn = createCallbackFcn(app, @SlysenoButtonPushed, true);
            app.SlysenoButton.Text = 'SLYŠENO';
            app.vektortestovacchfrekvencEditFieldLabel = uilabel(app.UIFigure);
            app.vektortestovacchfrekvencEditFieldLabel.Text = 'vektor testovacích frekvencí';
            app.vektortestovacchfrekvencEditField = uieditfield(app.UIFigure, 'text');
            app.vektortestovacchfrekvencEditField.ValueChangedFcn = createCallbackFcn(app, @vektortestovacchfrekvencEditFieldValueChanged, true);
            app.intenzitatestovanhotnuEditFieldLabel = uilabel(app.UIFigure);
            app.intenzitatestovanhotnuEditFieldLabel.Text = 'intenzita testovaného tónu';
            app.intenzitatestovanhotnuEditField = uieditfield(app.UIFigure, 'text');
            app.intenzitatestovanhotnuEditField.ValueChangedFcn = createCallbackFcn(app, @intenzitatestovanhotnuEditFieldValueChanged, true);
            app.frekvencemaskovachotnuEditFieldLabel = uilabel(app.UIFigure);
            app.frekvencemaskovachotnuEditFieldLabel.Text = 'frekvence maskovacího tónu';
            app.frekvencemaskovachotnuEditField = uieditfield(app.UIFigure, 'text');
            app.frekvencemaskovachotnuEditField.ValueChangedFcn = createCallbackFcn(app, @frekvencemaskovachotnuEditFieldValueChanged, true);
            app.umfrekvenceodEditFieldLabel = uilabel(app.UIFigure);
            app.umfrekvenceodEditFieldLabel.Text = 'šum frekvence  od';
            app.umfrekvenceodEditField = uieditfield(app.UIFigure, 'text');
            app.umfrekvenceodEditField.ValueChangedFcn = createCallbackFcn(app, @umfrekvenceodEditFieldValueChanged, true);
            app.umfrekvencedoEditFieldLabel = uilabel(app.UIFigure);
            app.umfrekvencedoEditFieldLabel.Text = 'šum frekvence  do';
            app.umfrekvencedoEditField = uieditfield(app.UIFigure, 'text');
            app.umfrekvencedoEditField.ValueChangedFcn = createCallbackFcn(app, @umfrekvencedoEditFieldValueChanged, true);
            app.umintenzitaEditFieldLabel = uilabel(app.UIFigure);
            app.umintenzitaEditFieldLabel.Text = 'šum intenzita';
            app.umintenzitaEditField = uieditfield(app.UIFigure, 'text');
            app.umintenzitaEditField.ValueChangedFcn = createCallbackFcn(app, @umintenzitaEditFieldValueChanged, true);
            app.ULOZITButton = uibutton(app.UIFigure, 'push');
            app.ULOZITButton.ButtonPushedFcn = createCallbackFcn(app, @ULOZITButtonPushed, true);
            app.ULOZITButton.Text = 'ULOŽIT';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name:';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Value = 'file name';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'foldr.png');
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hlavniOkno

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end