classdef nastaveni < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EditField                       matlab.ui.control.EditField
        OKButton                        matlab.ui.control.Button
        pasmoMaxFreq                    matlab.ui.control.NumericEditField
        doEditFieldLabel                matlab.ui.control.Label
        sumIntenzita                    matlab.ui.control.NumericEditField
        intenzita090dBEditField_2Label  matlab.ui.control.Label
        pasmoMinFreq                    matlab.ui.control.NumericEditField
        frekvennpsmo120000HzodEditFieldLabel  matlab.ui.control.Label
        dBLabel_2                       matlab.ui.control.Label
        HzLabel_3                       matlab.ui.control.Label
        PidnmaskovachoumuLabel          matlab.ui.control.Label
        intenzitaMaskTonu               matlab.ui.control.NumericEditField
        intenzitaLabel                  matlab.ui.control.Label
        dBLabel                         matlab.ui.control.Label
        frekvenceMaskTonu               matlab.ui.control.NumericEditField
        frekvencetnu120000HzEditFieldLabel  matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        PidnmaskovachotnuLabel          matlab.ui.control.Label
        ZvysovaniIntenzityTonu          matlab.ui.container.ButtonGroup
        schod                           matlab.ui.control.RadioButton
        spojite                         matlab.ui.control.RadioButton
        MaskovaniNetestovanehoUcha      matlab.ui.container.ButtonGroup
        maskNE                          matlab.ui.control.RadioButton
        maskANO                         matlab.ui.control.RadioButton
        HzLabel                         matlab.ui.control.Label
        FrequencyNewSet                 matlab.ui.control.NumericEditField
        TestovacfrekvenceHzLabel        matlab.ui.control.Label
        NastavenmenLabel                matlab.ui.control.Label
    end


    properties (Access = private)
        hlavniOkno % Description
        MaskovaniNetestovanehoUchaA_N = 0;
        ZvysovaniIntenzityTonuA_N = 0;
        xd = [];
    end

    methods (Access = public)

        function  importdata(app,value)
            app.FrequencyNewSet.Value = value;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hlavniOkno, freqVectHlavni)
            app.hlavniOkno = hlavniOkno;
            %app.FrequencyNewSet.Value = freqVectHlavni;
            app.xd = freqVectHlavni;
            s = [ sprintf('%g, ',app.xd(1:end-1)) sprintf('%g',app.xd(end))];
            app.FrequencyNewSet.Value = app.xd(2);
            app.EditField.Value = s ;
        end

        % Selection changed function: MaskovaniNetestovanehoUcha
        function MaskovaniNetestovanehoUchaSelectionChanged(app, event)
            selectedButton = app.MaskovaniNetestovanehoUcha.SelectedObject;

            switch selectedButton.Text
                case 'ANO'
                    app.MaskovaniNetestovanehoUchaA_N= 1;
                case 'NE'
                    app.MaskovaniNetestovanehoUchaA_N = 0;
            end


        end

        % Selection changed function: ZvysovaniIntenzityTonu
        function ZvysovaniIntenzityTonuSelectionChanged(app, event)
            selectedButton = app.ZvysovaniIntenzityTonu.SelectedObject;

            switch selectedButton.Text
                case 'spojité'
                    app.ZvysovaniIntenzityTonuA_N= 0;
                case 'schodovité'
                    app.ZvysovaniIntenzityTonuA_N = 1;
            end

        end

        % Value changed function: FrequencyNewSet
        function FrequencyNewSetValueChanged(app, event)
            value = app.FrequencyNewSet.Value;

        end

        % Value changed function: frekvenceMaskTonu
        function frekvenceMaskTonuValueChanged(app, event)
            value = app.frekvenceMaskTonu.Value;

        end

        % Value changed function: intenzitaMaskTonu
        function intenzitaMaskTonuValueChanged(app, event)
            value = app.intenzitaMaskTonu.Value;

        end

        % Value changed function: pasmoMinFreq
        function pasmoMinFreqValueChanged(app, event)
            value = app.pasmoMinFreq.Value;

        end

        % Value changed function: pasmoMaxFreq
        function pasmoMaxFreqValueChanged(app, event)
            value = app.pasmoMaxFreq.Value;

        end

        % Value changed function: sumIntenzita
        function sumIntenzitaValueChanged(app, event)
            value = app.sumIntenzita.Value;

        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            %(app,MaskovaniNetestovanehoUcha,ZvysovaniIntenzityTonu,frequencyNew,frekvenceMaskTonu,intenzitaMaskTonu,pasmoMinFreq,pasmoMaxFreq,sumIntenzita)
            importNastaveni(app.hlavniOkno,app.MaskovaniNetestovanehoUchaA_N , app.ZvysovaniIntenzityTonuA_N,app.FrequencyNewSet.Value,app.frekvenceMaskTonu.Value,app.intenzitaMaskTonu.Value,app.pasmoMinFreq.Value,app.pasmoMaxFreq.Value,app.sumIntenzita.Value,app.EditField.Value)
            delete(app)
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            % Filtrace neplatných znaků (pouze čísla a čárky)
            %valid_characters = '0123456789,';
            filtered_input = value(isstrprop(value, 'digit') | value == ',');

            % Nastavení filtrovaného vstupu zpět do textového pole
            app.EditField.Value = filtered_input;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NastavenmenLabel = uilabel(app.UIFigure);
            app.NastavenmenLabel.Text = 'Nastavení měření';
            app.TestovacfrekvenceHzLabel = uilabel(app.UIFigure);
            app.TestovacfrekvenceHzLabel.Text = 'Testovací frekvence (Hz):';
            app.FrequencyNewSet = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyNewSet.ValueChangedFcn = createCallbackFcn(app, @FrequencyNewSetValueChanged, true);
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.MaskovaniNetestovanehoUcha = uibuttongroup(app.UIFigure);
            app.MaskovaniNetestovanehoUcha.SelectionChangedFcn = createCallbackFcn(app, @MaskovaniNetestovanehoUchaSelectionChanged, true);
            app.MaskovaniNetestovanehoUcha.Title = 'Maskování netestovaného ucha';
            app.maskANO = uiradiobutton(app.MaskovaniNetestovanehoUcha);
            app.maskANO.Text = 'ANO';
            app.maskNE = uiradiobutton(app.MaskovaniNetestovanehoUcha);
            app.maskNE.Text = 'NE';
            app.maskNE.Value = true;
            app.ZvysovaniIntenzityTonu = uibuttongroup(app.UIFigure);
            app.ZvysovaniIntenzityTonu.SelectionChangedFcn = createCallbackFcn(app, @ZvysovaniIntenzityTonuSelectionChanged, true);
            app.ZvysovaniIntenzityTonu.Title = 'Zvyšování intenzity test. tónu';
            app.spojite = uiradiobutton(app.ZvysovaniIntenzityTonu);
            app.spojite.Text = 'spojité';
            app.spojite.Value = true;
            app.schod = uiradiobutton(app.ZvysovaniIntenzityTonu);
            app.schod.Text = 'schodovité';
            app.PidnmaskovachotnuLabel = uilabel(app.UIFigure);
            app.PidnmaskovachotnuLabel.Text = 'Přidání maskovacího tónu:';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = 'Hz';
            app.frekvencetnu120000HzEditFieldLabel = uilabel(app.UIFigure);
            app.frekvencetnu120000HzEditFieldLabel.Text = ' frekvence tónu (1-20 000 Hz)';
            app.frekvenceMaskTonu = uieditfield(app.UIFigure, 'numeric');
            app.frekvenceMaskTonu.ValueChangedFcn = createCallbackFcn(app, @frekvenceMaskTonuValueChanged, true);
            app.dBLabel = uilabel(app.UIFigure);
            app.dBLabel.Text = 'dB';
            app.intenzitaLabel = uilabel(app.UIFigure);
            app.intenzitaLabel.Text = 'intenzita  (0-90 dB)';
            app.intenzitaMaskTonu = uieditfield(app.UIFigure, 'numeric');
            app.intenzitaMaskTonu.ValueChangedFcn = createCallbackFcn(app, @intenzitaMaskTonuValueChanged, true);
            app.PidnmaskovachoumuLabel = uilabel(app.UIFigure);
            app.PidnmaskovachoumuLabel.Text = 'Přidání maskovacího šumu:';
            app.HzLabel_3 = uilabel(app.UIFigure);
            app.HzLabel_3.Text = 'Hz';
            app.dBLabel_2 = uilabel(app.UIFigure);
            app.dBLabel_2.Text = 'dB';
            app.frekvennpsmo120000HzodEditFieldLabel = uilabel(app.UIFigure);
            app.frekvennpsmo120000HzodEditFieldLabel.Text = 'frekvenční pásmo (1-20000 Hz) od:';
            app.pasmoMinFreq = uieditfield(app.UIFigure, 'numeric');
            app.pasmoMinFreq.ValueChangedFcn = createCallbackFcn(app, @pasmoMinFreqValueChanged, true);
            app.intenzita090dBEditField_2Label = uilabel(app.UIFigure);
            app.intenzita090dBEditField_2Label.Text = 'intenzita  (0-90 dB)';
            app.sumIntenzita = uieditfield(app.UIFigure, 'numeric');
            app.sumIntenzita.ValueChangedFcn = createCallbackFcn(app, @sumIntenzitaValueChanged, true);
            app.doEditFieldLabel = uilabel(app.UIFigure);
            app.doEditFieldLabel.Text = 'do:';
            app.pasmoMaxFreq = uieditfield(app.UIFigure, 'numeric');
            app.pasmoMaxFreq.ValueChangedFcn = createCallbackFcn(app, @pasmoMaxFreqValueChanged, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nastaveni(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end